classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MaxAccLabel                   matlab.ui.control.Label
        MaxVelLabel                   matlab.ui.control.Label
        MaxAmpLabel                   matlab.ui.control.Label
        ControlPanel                  matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        FrequencySweepSettingsPanel   matlab.ui.container.Panel
        MaxAmpEditField               matlab.ui.control.EditField
        MaxAmpEditFieldLabel          matlab.ui.control.Label
        MaxFreqEditField              matlab.ui.control.EditField
        MaxFreqEditFieldLabel         matlab.ui.control.Label
        FixedHarmonicPanel            matlab.ui.container.Panel
        FixedAmpEditField             matlab.ui.control.EditField
        FixedAmpEditFieldLabel        matlab.ui.control.Label
        FixedFreqEditField            matlab.ui.control.EditField
        FixedFreqEditFieldLabel       matlab.ui.control.Label
        HardwarePanel                 matlab.ui.container.Panel
        COMPortEditField              matlab.ui.control.EditField
        COMPortEditFieldLabel         matlab.ui.control.Label
        StatusTextArea                matlab.ui.control.TextArea
        Panel                         matlab.ui.container.Panel
        StartButton                   matlab.ui.control.Button
        WriteStepperButton            matlab.ui.control.Button
        ConnectSerialButton           matlab.ui.control.Button
        GenerateSignalButton          matlab.ui.control.Button
        GeneralSettingsPanel          matlab.ui.container.Panel
        FilterLengthEditFieldLabel    matlab.ui.control.Label
        MaxTEditFieldLabel            matlab.ui.control.Label
        StepsPerSecondEditFieldLabel  matlab.ui.control.Label
        MethodDropDownLabel           matlab.ui.control.Label
        FilterLengthEditField         matlab.ui.control.EditField
        MaxTEditField                 matlab.ui.control.EditField
        StepsPerSecondEditField       matlab.ui.control.EditField
        MethodDropDown                matlab.ui.control.DropDown
        AccSensorCheckBox             matlab.ui.control.CheckBox
        ShinozukaPanel                matlab.ui.container.Panel
        S0EditField                   matlab.ui.control.EditField
        S0EditFieldLabel              matlab.ui.control.Label
        BetaGEditField                matlab.ui.control.EditField
        betaEditFieldLabel            matlab.ui.control.Label
        WgEditField                   matlab.ui.control.EditField
        WgEditFieldLabel              matlab.ui.control.Label
        AmpPSDEditField               matlab.ui.control.EditField
        AmpPSDEditFieldLabel          matlab.ui.control.Label
        NOmegaEditField               matlab.ui.control.EditField
        NOmegaEditFieldLabel          matlab.ui.control.Label
        MaxFEditField                 matlab.ui.control.EditField
        MaxFEditFieldLabel            matlab.ui.control.Label
        PSDDropDown                   matlab.ui.control.DropDown
        PSDDropDownLabel              matlab.ui.control.Label
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes1                       matlab.ui.control.UIAxes
    end

    properties (Access = private)
        rootpath
        currentSimulationData
        dev
        t
        pos
        methodName
        signalName
    end   
    
    methods (Access = private)

        function x = toScalarDouble(app, v, fieldName)
            if isstring(v) || ischar(v)
                v = str2double(v);
            end
        
            if isempty(v) || ~isnumeric(v) || ~isscalar(v) || ~isfinite(v)
                error('%s must be a single numeric value.', fieldName);
            end
        
            x = double(v);
        end
        
        function params = collectParameters(app)
            params = struct();
        
            switch app.MethodDropDown.Value
                case 'Shinozuka'
                    params.usedMethod = MethodEnum.Shinozuka;
                case 'ShinozukaBenchmark'
                    params.usedMethod = MethodEnum.ShinozukaBenchmark;
                case 'FixedHarmonic'
                    params.usedMethod = MethodEnum.FixedHarmonic;
                case 'RandomHarmonic'
                    params.usedMethod = MethodEnum.RandomHarmonic;
                case 'EarthquakeRecord'
                    params.usedMethod = MethodEnum.EarthquakeRecord;
                case 'ImportSignal'
                    params.usedMethod = MethodEnum.ImportSignal;
                case 'FrequencySweep'
                    params.usedMethod = MethodEnum.FrequencySweep;
                case 'Impact'
                    params.usedMethod = MethodEnum.Impact;
                otherwise
                    error('Unsupported method selected.');
            end
        
            params.stepsPerSecond = app.toScalarDouble(app.StepsPerSecondEditField.Value, 'Steps per second');
            params.maxT = app.toScalarDouble(app.MaxTEditField.Value, 'MaxT');
            params.filterLength = app.toScalarDouble(app.FilterLengthEditField.Value, 'Filter length');
        
            params.accelerationSensorsActive = logical(app.AccSensorCheckBox.Value);
        
            params.usedPSD = char(string(app.PSDDropDown.Value));
            params.maxF = app.toScalarDouble(app.MaxFEditField.Value, 'MaxF');
            params.nOmega = app.toScalarDouble(app.NOmegaEditField.Value, 'NOmega');
            params.ampPSD = app.toScalarDouble(app.AmpPSDEditField.Value, 'AmpPSD');
            params.w_g = app.toScalarDouble(app.WgEditField.Value, 'Wg');
            params.beta_g = app.toScalarDouble(app.BetaGEditField.Value, 'BetaG');
            params.S_0 = app.toScalarDouble(app.S0EditField.Value, 'S0');
        
            params.fixedFreq = app.toScalarDouble(app.FixedFreqEditField.Value, 'Fixed frequency');
            params.fixedAmp = app.toScalarDouble(app.FixedAmpEditField.Value, 'Fixed amplitude');

            params.maxFreq = app.toScalarDouble(app.MaxFreqEditField.Value, 'Maximum Frequency');
            params.maxAmp = app.toScalarDouble(app.MaxAmpEditField.Value, 'Maximum Amplitude');
        end

        function plotSignalInternal(app)
            plot(app.UIAxes1, ...
                app.currentSimulationData.inputSignal(:,1), ...
                app.currentSimulationData.inputSignal(:,2));
            title(app.UIAxes1, 'Position [mm]');
            xlabel(app.UIAxes1, 'Time [s]');
            ylabel(app.UIAxes1, 'Position [mm]');
            grid(app.UIAxes1, 'on')
        
            plot(app.UIAxes2, ...
                app.currentSimulationData.inputVelocity(:,1), ...
                app.currentSimulationData.inputVelocity(:,2));
            title(app.UIAxes2, 'Velocity [mm/s]');
            xlabel(app.UIAxes2, 'Time [s]');
            ylabel(app.UIAxes2, 'Velocity [mm/s]');
            grid(app.UIAxes2, 'on')
        
            plot(app.UIAxes3, ...
                app.currentSimulationData.inputAcceleration(:,1), ...
                app.currentSimulationData.inputAcceleration(:,2)./1000);
            title(app.UIAxes3, 'Acceleration [m/s^2]');
            xlabel(app.UIAxes3, 'Time [s]');
            ylabel(app.UIAxes3, 'Acceleration [m/s^2]');
            grid(app.UIAxes3, 'on')
        end

        function updateStats(app)
            maxAmp = max(abs(app.currentSimulationData.inputSignal(:,2)));
            maxVel = max(abs(app.currentSimulationData.inputVelocity(:,2)));
            maxAcc = max(abs(app.currentSimulationData.inputAcceleration(:,2)./1000));
        
            app.MaxAmpLabel.Text = sprintf('Max Amp: %.2f mm', maxAmp);
            app.MaxVelLabel.Text = sprintf('Max Vel: %.2f mm/s', maxVel);
            app.MaxAccLabel.Text = sprintf('Max Acc: %.2f m/s^2', maxAcc);
        end

        function writeStatus(app, msg)
            app.StatusTextArea.Value = {msg};
        end

        function appendStatus(app, msg)
            oldText = app.StatusTextArea.Value;
            if ischar(oldText)
                oldText = {oldText};
            end
            app.StatusTextArea.Value = [oldText; {msg}];
        end
        
        function updateMethodPanels(app)
            app.ShinozukaPanel.Visible = 'off';
            app.FixedHarmonicPanel.Visible = 'off';
            app.GridLayout.RowHeight = {160, 0, 0, 0, 60, 120, 140, '1x'};
            switch app.MethodDropDown.Value
                case 'Shinozuka'
                    app.ShinozukaPanel.Visible = 'on';
                    app.FixedHarmonicPanel.Visible = 'off';
                    app.GridLayout.RowHeight = {160, 210, 0, 0, 60, 120, 140, '1x'};
        
                case 'FixedHarmonic'
                    app.ShinozukaPanel.Visible = 'off';
                    app.FixedHarmonicPanel.Visible = 'on';
                    app.GridLayout.RowHeight = {160, 0, 80, 0, 60, 120, 140, '1x'};
                case 'FrequencySweep'
                    app.ShinozukaPanel.Visible = 'off';
                    app.FixedHarmonicPanel.Visible = 'on';
                    app.GridLayout.RowHeight = {160, 0, 0, 80, 60, 120, 140, '1x'};
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.rootpath = [pwd filesep];
        
            addpath(genpath([app.rootpath 'Methods' filesep]));
            addpath(genpath([app.rootpath 'Functions' filesep]));
            addpath(genpath([app.rootpath 'Classes' filesep]));
        
            set(0,'defaulttextinterpreter','latex')
            set(0,'defaultAxesFontSize',12)

            app.updateMethodPanels();
        
            app.StatusTextArea.Value = {'App started.', 'Paths added successfully.'};
        end

        % Button pushed function: GenerateSignalButton
        function GenerateSignalButtonPushed(app, event)
            try
                % app.appendStatus('Checkpoint 1: Generate Signal button pressed');
        
                params = app.collectParameters();
                % app.appendStatus('Checkpoint 2: Parameters collected');
        
                currentSimulationData = ShakingData();
                currentSimulationData.accelerationSensorsActive = params.accelerationSensorsActive;
        
                t = [];
                pos = [];
                methodName = '';
                name = '';
        
                % app.appendStatus('Checkpoint 3: Entering method switch');
        
                switch params.usedMethod
        
                    case MethodEnum.Shinozuka
                        % app.appendStatus('Checkpoint 4: Inside Shinozuka case');

                        usedPSD = char(string(params.usedPSD));
                        maxT = double(params.maxT);
                        stepsPerSecond = double(params.stepsPerSecond);
                    
                        if isequal(usedPSD, 'LOL')
                            maxF = double(params.maxF);
                            nOmega = double(params.nOmega);
                            ampPSD = double(params.ampPSD);
                    
                            PSD = @(omega) ampPSD .* (abs(omega) < maxF*2*pi);
                            name = usedPSD;
                            currentSimulationData.psdFunc = PSD;
                    
                        elseif isequal(usedPSD, 'KT')
                            maxF = double(params.maxF);
                            nOmega = double(params.nOmega);
                            w_g = double(params.w_g);
                            beta_g = double(params.beta_g);
                            S_0 = double(params.S_0);
                    
                            PSD = @(omega) ((w_g^4 + (2*beta_g*w_g*omega).^2) ./ ...
                                ((w_g^2 - omega.^2).^2 + (2*beta_g*w_g*omega).^2)) .* S_0;
                    
                            name = usedPSD;
                            currentSimulationData.psdFunc = PSD;
                        else
                            error('Unknown PSD type.');
                        end
                    
                        app.writeStatus(sprintf('Shinozuka inputs: maxT=%g, stepsPerSecond=%g, maxF=%g, nOmega=%g', ...
                            maxT, stepsPerSecond, maxF, nOmega));
                    
                        [pos, t, ~] = SimulateShinozuka(PSD, maxT, maxF*2*pi, nOmega, ...
                            'nStepsPerSecond', stepsPerSecond);
                    
                        pos = pos - pos(1);
                        t = t(:);
                        pos = pos(:);
                    
                        currentSimulationData.inputSignal = [t, pos];
                        currentSimulationData.motorRate = stepsPerSecond;
                        methodName = 'Shinozuka';
        
                    case MethodEnum.ShinozukaBenchmark
                        % app.appendStatus('Checkpoint 4: Inside ShinozukaBenchmark case');
        
                        Shinozuka_amplitude_scaling = 3;
                        [pos, t, ~, ~, name] = SimulateShinozukaBenchmark(Shinozuka_amplitude_scaling);
        
                        t = t(:);
                        pos = pos(:);
        
                        app.writeStatus(sprintf('Checkpoint 5: t size = %s, pos size = %s', ...
                            mat2str(size(t)), mat2str(size(pos))));
        
                        currentSimulationData.inputSignal = [t, pos];
                        currentSimulationData.motorRate = (length(t)-1) / t(end);
                        methodName = 'ShinozukaBenchmark';
        
                    case MethodEnum.FixedHarmonic
                        % app.appendStatus('Checkpoint 4: Inside FixedHarmonic case');
        
                        fixedFreq = double(params.fixedFreq);
                        fixedAmp = double(params.fixedAmp);
                        maxT = double(params.maxT);
                        stepsPerSecond = double(params.stepsPerSecond);
                    
                        [pos,t,name] = SimulateFixedHarmonic(fixedFreq, fixedAmp, ...
                            maxT, 'nStepsPerSecond', stepsPerSecond);
                    
                        t = t(:);
                        pos = pos(:);
                        currentSimulationData.inputSignal = [t, pos];
                        currentSimulationData.motorRate = stepsPerSecond;
                        methodName = 'HarmonicSignal';
        
                    case MethodEnum.RandomHarmonic
                        % app.appendStatus('Checkpoint 4: Inside RandomHarmonic case');
        
                        maxT = double(params.maxT);
                        stepsPerSecond = double(params.stepsPerSecond);
        
                        [pos, t, name] = SimulateRandomHarmonic(1, 0.1, 5, maxT, ...
                            'nStepsPerSecond', stepsPerSecond);
        
                        t = t(:);
                        pos = pos(:);
        
                        app.writeStatus(sprintf('Checkpoint 5: t size = %s, pos size = %s', ...
                            mat2str(size(t)), mat2str(size(pos))));
        
                        currentSimulationData.inputSignal = [t, pos];
                        currentSimulationData.motorRate = stepsPerSecond;
                        methodName = 'RandomHarmonic';
        
                    case MethodEnum.EarthquakeRecord
                        % app.appendStatus('Checkpoint 4: Inside EarthquakeRecord case');
        
                        [pos, t, name] = SimulateEarthquake();
        
                        t = t(:);
                        pos = pos(:);
        
                        app.writeStatus(sprintf('Checkpoint 5: t size = %s, pos size = %s', ...
                            mat2str(size(t)), mat2str(size(pos))));
        
                        currentSimulationData.inputSignal = [t, pos];
                        currentSimulationData.motorRate = 1 / t(2);
                        currentSimulationData.signalGenerator = params.usedMethod;
                        methodName = 'Earthquake';
        
                    case MethodEnum.FrequencySweep
                        % app.appendStatus('Checkpoint 4: Inside FrequencySweep case');
        
                        maxT = double(params.maxT);
                        stepsPerSecond = double(params.stepsPerSecond);
        
                        maxF = params.maxFreq;
                        maxA = params.maxAmp;
        
                        [pos, t, name] = SimulateFrequencySweep(maxF*2*pi, maxA, maxT, ...
                            'nStepsPerSecond', stepsPerSecond);
        
                        t = t(:);
                        pos = pos(:);
        
                        app.writeStatus(sprintf('Checkpoint 5: t size = %s, pos size = %s', ...
                            mat2str(size(t)), mat2str(size(pos))));
        
                        currentSimulationData.inputSignal = [t, pos];
                        currentSimulationData.motorRate = stepsPerSecond;
                        methodName = 'FrequencySweep';
        
                    case MethodEnum.Impact
                        % app.appendStatus('Checkpoint 4: Inside Impact case');
        
                        stepsPerSecond = double(params.stepsPerSecond);
        
                        impactTime = 0.1;
                        maxD = 15;
        
                        [pos, t, name] = SimulateImpact(maxD, impactTime, ...
                            'nStepsPerSecond', stepsPerSecond);
        
                        t = t(:);
                        pos = pos(:);
        
                        app.writeStatus(sprintf('Checkpoint 5: t size = %s, pos size = %s', ...
                            mat2str(size(t)), mat2str(size(pos))));
        
                        currentSimulationData.inputSignal = [t, pos];
                        currentSimulationData.motorRate = stepsPerSecond;
                        methodName = 'Impact';
        
                    otherwise
                        error('This method is not yet wired in the app.');
                end
        
                % app.appendStatus(sprintf('Checkpoint 6: inputSignal size = %s', ...
                %     mat2str(size(currentSimulationData.inputSignal))));
        
                if size(currentSimulationData.inputSignal, 2) ~= 2
                    error('inputSignal must be N x 2.');
                end
        
                currentSimulationData.simulationType = params.usedMethod;
        
                % app.appendStatus('Checkpoint 7: Before FilterMotorInput');
                currentSimulationData = FilterMotorInput(currentSimulationData, double(params.filterLength));
        
                % app.appendStatus('Checkpoint 8: After FilterMotorInput');
                currentSimulationData = currentSimulationData.Setup();
        
                % app.appendStatus('Checkpoint 9: After Setup');
        
                app.currentSimulationData = currentSimulationData;
                app.t = t;
                app.pos = pos;
                app.methodName = methodName;
                app.signalName = name;
        
                app.plotSignalInternal();
                app.updateStats();
                app.appendStatus(sprintf('Signal generated: %s', methodName));
        
            catch ME
                app.writeStatus(['Error: ' ME.message]);
                app.appendStatus(getReport(ME, 'extended', 'hyperlinks', 'off'));
                uialert(app.UIFigure, ME.message, 'Generate Signal Error');
            end
        end

        % Button pushed function: ConnectSerialButton
        function ConnectSerialButtonPushed(app, event)
            try
                comPort = char(string(app.COMPortEditField.Value));
        
                if ~isempty(app.dev)
                    try
                        clear app.dev
                    catch
                    end
                    app.dev = [];
                end
        
                app.dev = serialport(comPort, 921600);
                pause(0.1);
                flush(app.dev);
        
                app.writeStatus(['Connected to ' comPort]);
            catch ME
                app.writeStatus(['Error: ' ME.message]);
                app.appendStatus(getReport(ME, 'extended', 'hyperlinks', 'off'));
                uialert(app.UIFigure, ME.message, 'Serial Connection Error');
            end
        end

        % Button pushed function: WriteStepperButton
        function WriteStepperButtonPushed(app, event)
            try
                if isempty(app.currentSimulationData)
                    error('Generate signal first.');
                end
        
                if isempty(app.dev)
                    error('Connect serial port first.');
                end
        
                selection = uiconfirm(app.UIFigure, ...
                    'Continue with writing data to stepper?', ...
                    'Confirm', ...
                    'Options', {'Yes','No'}, ...
                    'DefaultOption', 1, ...
                    'CancelOption', 2);
        
                if ~strcmp(selection, 'Yes')
                    app.appendStatus('Write to stepper cancelled by user.');
                    return;
                end
        
                app.currentSimulationData = WriteMarvCode(app.currentSimulationData);
                [succ, ~] = SendDataToMotor(app.currentSimulationData, app.dev);
                if succ == 1
                    app.appendStatus('Data written to stepper.');
                else
                    app.appendStatus('Failed to send to USB');
                end
                
            catch ME
                app.writeStatus(['Error: ' ME.message]);
                app.appendStatus(getReport(ME, 'extended', 'hyperlinks', 'off'));
                uialert(app.UIFigure, ME.message, 'Write Stepper Error');
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
             try
                if isempty(app.currentSimulationData)
                    error('Generate signal first.');
                end
        
                if isempty(app.dev)
                    error('Connect serial port first.');
                end
        
                selection = uiconfirm(app.UIFigure, ...
                    'Continue with starting the experiment?', ...
                    'Confirm', ...
                    'Options', {'Yes','No'}, ...
                    'DefaultOption', 1, ...
                    'CancelOption', 2);
        
                if ~strcmp(selection, 'Yes')
                    app.appendStatus('Experiment start cancelled by user.');
                    return;
                end
        
                app.currentSimulationData = StartExperiment(app.currentSimulationData, app.dev);
        
                if isempty(app.methodName)
                    app.methodName = 'UnknownMethod';
                end
                if isempty(app.signalName)
                    app.signalName = 'UnknownSignal';
                end
        
                delimiter = filesep;
                fName = [app.rootpath 'MarvCode' delimiter ...
                    char(datetime('now','Format','yMMdd_HHmmss_SSS')) '_' ...
                    app.methodName '_' app.signalName '.mat'];
        
                app.currentSimulationData.fileName = fName;
        
                currentSimulationData = app.currentSimulationData; %#ok<NASGU>
                save(app.currentSimulationData.fileName, 'currentSimulationData');
        
                app.appendStatus(['Experiment completed and saved: ' fName]);
            catch ME
                app.appendStatus(['Error: ' ME.message]);
                app.appendStatus(getReport(ME, 'extended', 'hyperlinks', 'off'));
                uialert(app.UIFigure, ME.message, 'Experiment Error');
            end
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged2(app, event)
            app.writeStatus(sprintf('Method %s selected', app.MethodDropDown.Value));
            app.updateMethodPanels();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Title', 'Interpreter', 'latex')
            xlabel(app.UIAxes1, 'X', 'Interpreter', 'latex')
            ylabel(app.UIAxes1, 'Y', 'Interpreter', 'latex')
            zlabel(app.UIAxes1, 'Z', 'Interpreter', 'latex')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title', 'Interpreter', 'latex')
            xlabel(app.UIAxes2, 'X', 'Interpreter', 'latex')
            ylabel(app.UIAxes2, 'Y', 'Interpreter', 'latex')
            zlabel(app.UIAxes2, 'Z', 'Interpreter', 'latex')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title', 'Interpreter', 'latex')
            xlabel(app.UIAxes3, 'X', 'Interpreter', 'latex')
            ylabel(app.UIAxes3, 'Y', 'Interpreter', 'latex')
            zlabel(app.UIAxes3, 'Z', 'Interpreter', 'latex')
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.GridLayout = uigridlayout(app.ControlPanel);
            app.ShinozukaPanel = uipanel(app.GridLayout);
            app.ShinozukaPanel.Title = 'Shinozuka settings';
            app.PSDDropDownLabel = uilabel(app.ShinozukaPanel);
            app.PSDDropDownLabel.Text = 'PSD';
            app.PSDDropDown = uidropdown(app.ShinozukaPanel);
            app.PSDDropDown.Items = {'KT', 'LOL'};
            app.PSDDropDown.Value = 'KT';
            app.MaxFEditFieldLabel = uilabel(app.ShinozukaPanel);
            app.MaxFEditFieldLabel.Text = 'Max F';
            app.MaxFEditField = uieditfield(app.ShinozukaPanel, 'text');
            app.MaxFEditField.InputType = 'digits';
            app.MaxFEditField.Value = '15';
            app.NOmegaEditFieldLabel = uilabel(app.ShinozukaPanel);
            app.NOmegaEditFieldLabel.Text = 'N Omega';
            app.NOmegaEditField = uieditfield(app.ShinozukaPanel, 'text');
            app.NOmegaEditField.InputType = 'digits';
            app.NOmegaEditField.Value = '150';
            app.AmpPSDEditFieldLabel = uilabel(app.ShinozukaPanel);
            app.AmpPSDEditFieldLabel.Text = 'Amp PSD';
            app.AmpPSDEditField = uieditfield(app.ShinozukaPanel, 'text');
            app.AmpPSDEditField.Value = '0.01';
            app.WgEditFieldLabel = uilabel(app.ShinozukaPanel);
            app.WgEditFieldLabel.Text = 'Wg';
            app.WgEditField = uieditfield(app.ShinozukaPanel, 'text');
            app.WgEditField.Value = '56.54866707';
            app.betaEditFieldLabel = uilabel(app.ShinozukaPanel);
            app.betaEditFieldLabel.Text = 'beta';
            app.BetaGEditField = uieditfield(app.ShinozukaPanel, 'text');
            app.BetaGEditField.Value = '0.6';
            app.S0EditFieldLabel = uilabel(app.ShinozukaPanel);
            app.S0EditFieldLabel.Text = 'S0';
            app.S0EditField = uieditfield(app.ShinozukaPanel, 'text');
            app.S0EditField.Value = '0.01';
            app.GeneralSettingsPanel = uipanel(app.GridLayout);
            app.GeneralSettingsPanel.Title = 'General Settings';
            app.AccSensorCheckBox = uicheckbox(app.GeneralSettingsPanel);
            app.AccSensorCheckBox.Text = 'Acc Sensor';
            app.MethodDropDown = uidropdown(app.GeneralSettingsPanel);
            app.MethodDropDown.Items = {'Shinozuka', 'ShinozukaBenchmark', 'FixedHarmonic', 'RandomHarmonic', 'EarthquakeRecord', 'ImportSignal', 'FrequencySweep', 'Impact'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged2, true);
            app.MethodDropDown.Value = 'Shinozuka';
            app.StepsPerSecondEditField = uieditfield(app.GeneralSettingsPanel, 'text');
            app.StepsPerSecondEditField.InputType = 'digits';
            app.StepsPerSecondEditField.Value = '100';
            app.MaxTEditField = uieditfield(app.GeneralSettingsPanel, 'text');
            app.MaxTEditField.InputType = 'digits';
            app.MaxTEditField.Value = '10';
            app.FilterLengthEditField = uieditfield(app.GeneralSettingsPanel, 'text');
            app.FilterLengthEditField.InputType = 'digits';
            app.FilterLengthEditField.Value = '3';
            app.MethodDropDownLabel = uilabel(app.GeneralSettingsPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.StepsPerSecondEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.StepsPerSecondEditFieldLabel.Text = 'Steps Per Second';
            app.MaxTEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.MaxTEditFieldLabel.Text = 'Max T';
            app.FilterLengthEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.FilterLengthEditFieldLabel.Text = 'Filter Length';
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Panel';
            app.GenerateSignalButton = uibutton(app.Panel, 'push');
            app.GenerateSignalButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSignalButtonPushed, true);
            app.GenerateSignalButton.Text = 'Generate Signal';
            app.ConnectSerialButton = uibutton(app.Panel, 'push');
            app.ConnectSerialButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectSerialButtonPushed, true);
            app.ConnectSerialButton.Text = 'Connect Serial';
            app.WriteStepperButton = uibutton(app.Panel, 'push');
            app.WriteStepperButton.ButtonPushedFcn = createCallbackFcn(app, @WriteStepperButtonPushed, true);
            app.WriteStepperButton.Text = 'Write Stepper';
            app.StartButton = uibutton(app.Panel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StatusTextArea = uitextarea(app.GridLayout);
            app.StatusTextArea.Value = {'App started.'};
            app.HardwarePanel = uipanel(app.GridLayout);
            app.HardwarePanel.Title = 'Hardware';
            app.COMPortEditFieldLabel = uilabel(app.HardwarePanel);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.COMPortEditField = uieditfield(app.HardwarePanel, 'text');
            app.COMPortEditField.Value = 'COM3';
            app.FixedHarmonicPanel = uipanel(app.GridLayout);
            app.FixedHarmonicPanel.Title = 'Fixed harmonic settings';
            app.FixedFreqEditFieldLabel = uilabel(app.FixedHarmonicPanel);
            app.FixedFreqEditFieldLabel.Text = 'Fixed Freq';
            app.FixedFreqEditField = uieditfield(app.FixedHarmonicPanel, 'text');
            app.FixedFreqEditField.InputType = 'digits';
            app.FixedFreqEditField.Value = '1';
            app.FixedAmpEditFieldLabel = uilabel(app.FixedHarmonicPanel);
            app.FixedAmpEditFieldLabel.Text = 'Fixed Amp';
            app.FixedAmpEditField = uieditfield(app.FixedHarmonicPanel, 'text');
            app.FixedAmpEditField.InputType = 'digits';
            app.FixedAmpEditField.Value = '10';
            app.FrequencySweepSettingsPanel = uipanel(app.GridLayout);
            app.FrequencySweepSettingsPanel.Title = 'Frequency Sweep Settings';
            app.MaxFreqEditFieldLabel = uilabel(app.FrequencySweepSettingsPanel);
            app.MaxFreqEditFieldLabel.Text = 'Max Freq';
            app.MaxFreqEditField = uieditfield(app.FrequencySweepSettingsPanel, 'text');
            app.MaxFreqEditField.Value = '10';
            app.MaxAmpEditFieldLabel = uilabel(app.FrequencySweepSettingsPanel);
            app.MaxAmpEditFieldLabel.Text = 'Max Amp';
            app.MaxAmpEditField = uieditfield(app.FrequencySweepSettingsPanel, 'text');
            app.MaxAmpEditField.Value = '0.5';
            app.MaxAmpLabel = uilabel(app.UIFigure);
            app.MaxAmpLabel.Text = 'Max Amp: - ';
            app.MaxVelLabel = uilabel(app.UIFigure);
            app.MaxVelLabel.Text = 'Max Vel: - ';
            app.MaxAccLabel = uilabel(app.UIFigure);
            app.MaxAccLabel.Text = 'Max Acc: -';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end